classdef App_historiador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DATOSUTR2Panel                 matlab.ui.container.Panel
        DIPswitch3Switch_2             matlab.ui.control.RockerSwitch
        DIPswitch3Switch_2Label        matlab.ui.control.Label
        Valor_ADC2                     matlab.ui.control.Label
        ADCLabel_2                     matlab.ui.control.Label
        ALARMALamp_2                   matlab.ui.control.Lamp
        ALARMALamp_2Label              matlab.ui.control.Label
        RelaysalidaSwitch_2            matlab.ui.control.ToggleSwitch
        RelaysalidaSwitch_2Label       matlab.ui.control.Label
        RelaybateraSwitch_2            matlab.ui.control.ToggleSwitch
        RelaybateraSwitch_2Label       matlab.ui.control.Label
        RelayalimentacinSwitch_2       matlab.ui.control.ToggleSwitch
        RelayalimentacinSwitch_2Label  matlab.ui.control.Label
        DIPswitch2Switch_2             matlab.ui.control.RockerSwitch
        DIPswitch2Switch_2Label        matlab.ui.control.Label
        DIPswitch1Switch_2             matlab.ui.control.RockerSwitch
        DIPswitch1Switch_2Label        matlab.ui.control.Label
        BocinaKnob_2                   matlab.ui.control.DiscreteKnob
        BocinaKnob_2Label              matlab.ui.control.Label
        BateraKnob_2                   matlab.ui.control.DiscreteKnob
        BateraKnob_2Label              matlab.ui.control.Label
        LED1Lamp_2                     matlab.ui.control.Lamp
        LED1Lamp_2Label                matlab.ui.control.Label
        LED2Lamp_2                     matlab.ui.control.Lamp
        LED2Lamp_2Label                matlab.ui.control.Label
        LED3Lamp_2                     matlab.ui.control.Lamp
        LED3Lamp_2Label                matlab.ui.control.Label
        HISTORIADORLabel               matlab.ui.control.Label
        DATOSUTR1Panel                 matlab.ui.container.Panel
        DIPswitch3Switch               matlab.ui.control.RockerSwitch
        DIPswitch3SwitchLabel          matlab.ui.control.Label
        Valor_ADC                      matlab.ui.control.Label
        ADCLabel                       matlab.ui.control.Label
        ALARMALamp                     matlab.ui.control.Lamp
        ALARMALampLabel                matlab.ui.control.Label
        RelaysalidaSwitch              matlab.ui.control.ToggleSwitch
        RelaysalidaSwitchLabel         matlab.ui.control.Label
        RelaybateraSwitch              matlab.ui.control.ToggleSwitch
        RelaybateraSwitchLabel         matlab.ui.control.Label
        RelayalimentacinSwitch         matlab.ui.control.ToggleSwitch
        RelayalimentacinSwitchLabel    matlab.ui.control.Label
        DIPswitch2Switch               matlab.ui.control.RockerSwitch
        DIPswitch2SwitchLabel          matlab.ui.control.Label
        DIPswitch1Switch               matlab.ui.control.RockerSwitch
        DIPswitch1SwitchLabel          matlab.ui.control.Label
        BocinaKnob                     matlab.ui.control.DiscreteKnob
        BocinaKnobLabel                matlab.ui.control.Label
        BateraKnob                     matlab.ui.control.DiscreteKnob
        BateraKnobLabel                matlab.ui.control.Label
        LED1Lamp                       matlab.ui.control.Lamp
        LED1LampLabel                  matlab.ui.control.Label
        LED2Lamp                       matlab.ui.control.Lamp
        LED2LampLabel                  matlab.ui.control.Label
        LED3Lamp                       matlab.ui.control.Lamp
        LED3LampLabel                  matlab.ui.control.Label
        Envio                          matlab.ui.control.Button
        Datos_enviados                 matlab.ui.control.EditField
        EnviarUTRsLabel                matlab.ui.control.Label
        Switch_inicio                  matlab.ui.control.ToggleSwitch
        HistoriadorLabel               matlab.ui.control.Label
    end


    properties (Access = public)
        % Opción para poder generar sockets que reciben la información
        uSocket1 = udpport("datagram", "LocalPort",2000,"EnablePortSharing",true);

        % Variables que deben cambiar con los diferentes valores ingresados por los sockets
        % Formato de mensaje por socket: #(número de utr),boton1,boton2,switch1,switch2,switch3,relay1,relay2,relay3,ADC,alarma,Led1,Led2,Led3
        Boton1 = 0;
        Boton2 = 0;
        Switch1 = 0;
        Switch2 = 0;
        Switch3 = 0;
        relay1 = 0;
        relay2 = 0;
        relay3 = 0;
        ADC = 0;
        alarma = 0;
        Led1 = 0;
        Led2 = 0;
        Led3 = 0;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch_inicio
        function Switch_inicioValueChanged(app, event)
            % Se guarda el valor del switch On/Off utilizado
            value = app.Switch_inicio.Value;

            % Ciclo while == On (mientras switch esté en "On"), se recibe
            % la señal por parte de los UTR
            while value == "On"
                
                app.uSocket1.EnableBroadcast = true;

                % Se escribe el mensaje hacia el UTR
                write(app.uSocket1, "Hola, soy Matlab", "string", "192.168.0.255", 2000);

                % Se verifica si el uSocket1 recibió información. Si existe
                % información, entonces envia un número 1
                uSocket1_Recepcion = app.uSocket1.NumDatagramsAvailable;
            
                % Lectura del socket
                datos_Socket1 = read(app.uSocket1, uSocket1_Recepcion, "string");

                app.Valor_ADC.Text = datos_Socket1(1).Data;

                % Comparar datos para saber a que panel de UTR afectar
                comparacion_datos1 = strncmp("#1",datos_Socket1(1).Data,2);
                comparacion_datos2 = strncmp(datos_Socket1(1).Data,"#2",2);

                % Si se recibe un mensaje del utr 1, hacer lo siguiente
                if comparacion_datos1 == 1

                    % Separar los caracteres del mensaje recibido por el
                    % socket utilizando como delimitador, las comas
                    variables_utr1 = split(datos_Socket1(1).Data,",");

                    % ------------------- Primer Botón -------------------
                    % Si el primer Knob adquiere un 1 o un 0, se obtiene un
                    % cambio en el mismo
                    if contains(variables_utr1(2),"1") % #1,1,1,0,2,1
                        app.BateraKnob.Value = "V. PK";
                    elseif contains(variables_utr1(2), "0")
                        app.BateraKnob.Value = "V. RMS";
                    end

                    % ------------------ Segundo Botón -------------------
                    % Si el segundo Knob adquiere un 1 o un 0, se obtiene un
                    % cambio en el mismo
                    if contains(variables_utr1(3),"1") % #1,1,1,0,2,1
                        app.BocinaKnob.Value = "Bocina desactivada";
                    elseif contains(variables_utr1(3), "0")
                        app.BocinaKnob.Value = "Bocina activada";
                    end

                    % ------------------ DIP switch 1 --------------------
                    % Si el DIP switch adquiere un 1 o un 0, se obtiene un
                    % cambio en el mismo
                    if contains(variables_utr1(4),"1") % #1,1,1,0,2,1
                        app.DIPswitch1Switch.Value = "On";
                    elseif contains(variables_utr1(4), "0")
                        app.DIPswitch1Switch.Value = "Off";
                    end

                    % ------------------ DIP switch 2 --------------------
                    % Si el DIP switch adquiere un 1 o un 0, se obtiene un
                    % cambio en el mismo
                    if contains(variables_utr1(5),"1") % #1,1,1,0,2,1
                        app.DIPswitch2Switch.Value = "On";
                    elseif contains(variables_utr1(5), "0")
                        app.DIPswitch2Switch.Value = "Off";
                    end

                    % ------------------ DIP switch 3 --------------------
                    % Si el DIP switch adquiere un 1 o un 0, se obtiene un
                    % cambio en el mismo
                    if contains(variables_utr1(6),"1") % #1,1,1,0,2,1
                        app.DIPswitch2Switch.Value = "On";
                    elseif contains(variables_utr1(6), "0")
                        app.DIPswitch2Switch.Value = "Off";
                    end
                end

                value = app.Switch_inicio.Value;
                pause(0.5);

            end
            if value == "Off"
                
            end

        end

        % Button pushed function: Envio
        function EnvioButtonPushed(app, event)
            value1 = app.Switch_inicio.Value;
            
            if value1 == "On"

                % Se habilita la opción para generar comunicación por broadcast
                app.uSocket1.EnableBroadcast = true;

                % Se escribe el mensaje hacia el UTR
                write(app.uSocket1, app.Datos_enviados.Value, "string", "192.168.0.255", 2000);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistoriadorLabel = uilabel(app.UIFigure);
            app.HistoriadorLabel.Text = {'Historiador'; ''};
            app.Switch_inicio = uiswitch(app.UIFigure, 'toggle');
            app.Switch_inicio.ValueChangedFcn = createCallbackFcn(app, @Switch_inicioValueChanged, true);
            app.EnviarUTRsLabel = uilabel(app.UIFigure);
            app.EnviarUTRsLabel.Text = {'Datos a enviar a los UTRs'; ''; ''};
            app.Datos_enviados = uieditfield(app.UIFigure, 'text');
            app.Envio = uibutton(app.UIFigure, 'push');
            app.Envio.ButtonPushedFcn = createCallbackFcn(app, @EnvioButtonPushed, true);
            app.Envio.Text = {'Enviar'; ''};
            app.DATOSUTR1Panel = uipanel(app.UIFigure);
            app.DATOSUTR1Panel.Title = 'DATOS UTR 1';
            app.LED3LampLabel = uilabel(app.DATOSUTR1Panel);
            app.LED3LampLabel.Text = {'LED 3'; ''};
            app.LED3Lamp = uilamp(app.DATOSUTR1Panel);
            app.LED2LampLabel = uilabel(app.DATOSUTR1Panel);
            app.LED2LampLabel.Text = 'LED 2';
            app.LED2Lamp = uilamp(app.DATOSUTR1Panel);
            app.LED1LampLabel = uilabel(app.DATOSUTR1Panel);
            app.LED1LampLabel.Text = {'LED 1'; ''; ''};
            app.LED1Lamp = uilamp(app.DATOSUTR1Panel);
            app.BateraKnobLabel = uilabel(app.DATOSUTR1Panel);
            app.BateraKnobLabel.Text = {'Batería'; ''};
            app.BateraKnob = uiknob(app.DATOSUTR1Panel, 'discrete');
            app.BateraKnob.Items = {'V. RMS', 'V. PK'};
            app.BateraKnob.Value = 'V. RMS';
            app.BocinaKnobLabel = uilabel(app.DATOSUTR1Panel);
            app.BocinaKnobLabel.Text = 'Bocina';
            app.BocinaKnob = uiknob(app.DATOSUTR1Panel, 'discrete');
            app.BocinaKnob.Items = {'Bocina activada', 'Bocina desactivada'};
            app.BocinaKnob.Value = 'Bocina activada';
            app.DIPswitch1SwitchLabel = uilabel(app.DATOSUTR1Panel);
            app.DIPswitch1SwitchLabel.Text = {'DIP switch 1'; ''};
            app.DIPswitch1Switch = uiswitch(app.DATOSUTR1Panel, 'rocker');
            app.DIPswitch2SwitchLabel = uilabel(app.DATOSUTR1Panel);
            app.DIPswitch2SwitchLabel.Text = {'DIP switch 2'; ''};
            app.DIPswitch2Switch = uiswitch(app.DATOSUTR1Panel, 'rocker');
            app.RelayalimentacinSwitchLabel = uilabel(app.DATOSUTR1Panel);
            app.RelayalimentacinSwitchLabel.Text = 'Relay alimentación';
            app.RelayalimentacinSwitch = uiswitch(app.DATOSUTR1Panel, 'toggle');
            app.RelaybateraSwitchLabel = uilabel(app.DATOSUTR1Panel);
            app.RelaybateraSwitchLabel.Text = {'Relay batería'; ''};
            app.RelaybateraSwitch = uiswitch(app.DATOSUTR1Panel, 'toggle');
            app.RelaysalidaSwitchLabel = uilabel(app.DATOSUTR1Panel);
            app.RelaysalidaSwitchLabel.Text = 'Relay salida';
            app.RelaysalidaSwitch = uiswitch(app.DATOSUTR1Panel, 'toggle');
            app.ALARMALampLabel = uilabel(app.DATOSUTR1Panel);
            app.ALARMALampLabel.Text = 'ALARMA';
            app.ALARMALamp = uilamp(app.DATOSUTR1Panel);
            app.ADCLabel = uilabel(app.DATOSUTR1Panel);
            app.ADCLabel.Text = {'ADC'; ''};
            app.Valor_ADC = uilabel(app.DATOSUTR1Panel);
            app.DIPswitch3SwitchLabel = uilabel(app.DATOSUTR1Panel);
            app.DIPswitch3SwitchLabel.Text = {'DIP switch 3'; ''};
            app.DIPswitch3Switch = uiswitch(app.DATOSUTR1Panel, 'rocker');
            app.HISTORIADORLabel = uilabel(app.UIFigure);
            app.HISTORIADORLabel.Text = {'HISTORIADOR'; ''};
            app.DATOSUTR2Panel = uipanel(app.UIFigure);
            app.DATOSUTR2Panel.Title = 'DATOS UTR 2';
            app.LED3Lamp_2Label = uilabel(app.DATOSUTR2Panel);
            app.LED3Lamp_2Label.Text = {'LED 3'; ''};
            app.LED3Lamp_2 = uilamp(app.DATOSUTR2Panel);
            app.LED2Lamp_2Label = uilabel(app.DATOSUTR2Panel);
            app.LED2Lamp_2Label.Text = 'LED 2';
            app.LED2Lamp_2 = uilamp(app.DATOSUTR2Panel);
            app.LED1Lamp_2Label = uilabel(app.DATOSUTR2Panel);
            app.LED1Lamp_2Label.Text = {'LED 1'; ''; ''};
            app.LED1Lamp_2 = uilamp(app.DATOSUTR2Panel);
            app.BateraKnob_2Label = uilabel(app.DATOSUTR2Panel);
            app.BateraKnob_2Label.Text = {'Batería'; ''};
            app.BateraKnob_2 = uiknob(app.DATOSUTR2Panel, 'discrete');
            app.BateraKnob_2.Items = {'V.RMS', 'V. PK'};
            app.BateraKnob_2.Value = 'V.RMS';
            app.BocinaKnob_2Label = uilabel(app.DATOSUTR2Panel);
            app.BocinaKnob_2Label.Text = 'Bocina';
            app.BocinaKnob_2 = uiknob(app.DATOSUTR2Panel, 'discrete');
            app.BocinaKnob_2.Items = {'Bocina activada', 'Bocina desactivada'};
            app.BocinaKnob_2.Value = 'Bocina activada';
            app.DIPswitch1Switch_2Label = uilabel(app.DATOSUTR2Panel);
            app.DIPswitch1Switch_2Label.Text = {'DIP switch 1'; ''};
            app.DIPswitch1Switch_2 = uiswitch(app.DATOSUTR2Panel, 'rocker');
            app.DIPswitch2Switch_2Label = uilabel(app.DATOSUTR2Panel);
            app.DIPswitch2Switch_2Label.Text = {'DIP switch 2'; ''};
            app.DIPswitch2Switch_2 = uiswitch(app.DATOSUTR2Panel, 'rocker');
            app.RelayalimentacinSwitch_2Label = uilabel(app.DATOSUTR2Panel);
            app.RelayalimentacinSwitch_2Label.Text = 'Relay alimentación';
            app.RelayalimentacinSwitch_2 = uiswitch(app.DATOSUTR2Panel, 'toggle');
            app.RelaybateraSwitch_2Label = uilabel(app.DATOSUTR2Panel);
            app.RelaybateraSwitch_2Label.Text = {'Relay batería'; ''};
            app.RelaybateraSwitch_2 = uiswitch(app.DATOSUTR2Panel, 'toggle');
            app.RelaysalidaSwitch_2Label = uilabel(app.DATOSUTR2Panel);
            app.RelaysalidaSwitch_2Label.Text = 'Relay salida';
            app.RelaysalidaSwitch_2 = uiswitch(app.DATOSUTR2Panel, 'toggle');
            app.ALARMALamp_2Label = uilabel(app.DATOSUTR2Panel);
            app.ALARMALamp_2Label.Text = 'ALARMA';
            app.ALARMALamp_2 = uilamp(app.DATOSUTR2Panel);
            app.ADCLabel_2 = uilabel(app.DATOSUTR2Panel);
            app.ADCLabel_2.Text = 'ADC';
            app.Valor_ADC2 = uilabel(app.DATOSUTR2Panel);
            app.Valor_ADC2.Text = 'Label2';
            app.DIPswitch3Switch_2Label = uilabel(app.DATOSUTR2Panel);
            app.DIPswitch3Switch_2Label.Text = {'DIP switch 3'; ''};
            app.DIPswitch3Switch_2 = uiswitch(app.DATOSUTR2Panel, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_historiador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end